classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button_13               matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Label_4                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Image5                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button_5                matlab.ui.control.ToggleButton
        Button_4                matlab.ui.control.ToggleButton
        Button_3                matlab.ui.control.ToggleButton
        Button_2                matlab.ui.control.ToggleButton
        Tab_3                   matlab.ui.container.Tab
        Label_28                matlab.ui.control.Label
        Image7_2                matlab.ui.control.Image
        TabGroup2               matlab.ui.container.TabGroup
        Tab_4                   matlab.ui.container.Tab
        cSpinner_2              matlab.ui.control.Spinner
        cSpinner_2Label         matlab.ui.control.Label
        gxyclog1fxyLabel        matlab.ui.control.Label
        Tab_5                   matlab.ui.container.Tab
        cSpinner                matlab.ui.control.Spinner
        cSpinnerLabel           matlab.ui.control.Label
        gxycefxy1Label          matlab.ui.control.Label
        Slider_3                matlab.ui.control.Slider
        Label_14                matlab.ui.control.Label
        Slider_2                matlab.ui.control.Slider
        Label_13                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        Image6_2                matlab.ui.control.Image
        Tab_2                   matlab.ui.container.Tab
        ySlider                 matlab.ui.control.Slider
        ySliderLabel            matlab.ui.control.Label
        xSlider                 matlab.ui.control.Slider
        xSliderLabel            matlab.ui.control.Label
        Knob                    matlab.ui.control.Knob
        Label_27                matlab.ui.control.Label
        Image7                  matlab.ui.control.Image
        Label_8                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Image6                  matlab.ui.control.Image
        Slider                  matlab.ui.control.Slider
        Label_5                 matlab.ui.control.Label
        Tab_6                   matlab.ui.container.Tab
        TabGroup3               matlab.ui.container.TabGroup
        Tab_8                   matlab.ui.container.Tab
        Image12_5               matlab.ui.control.Image
        Image12                 matlab.ui.control.Image
        Button_8                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        varianceEditField       matlab.ui.control.NumericEditField
        varianceEditFieldLabel  matlab.ui.control.Label
        meanEditField           matlab.ui.control.NumericEditField
        meanEditFieldLabel      matlab.ui.control.Label
        Image6_3                matlab.ui.control.Image
        Tab_9                   matlab.ui.container.Tab
        Image12_7               matlab.ui.control.Image
        Image12_6               matlab.ui.control.Image
        Button_10               matlab.ui.control.Button
        Button_9                matlab.ui.control.Button
        densitySpinner          matlab.ui.control.Spinner
        densitySpinnerLabel     matlab.ui.control.Label
        Image12_3               matlab.ui.control.Image
        Tab_10                  matlab.ui.container.Tab
        Image12_9               matlab.ui.control.Image
        Image12_8               matlab.ui.control.Image
        Button_12               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Spinner                 matlab.ui.control.Spinner
        SpinnerLabel            matlab.ui.control.Label
        Image12_4               matlab.ui.control.Image
        Tab_7                   matlab.ui.container.Tab
        DropDown_4              matlab.ui.control.DropDown
        Label_26                matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_21                matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        Button_6                matlab.ui.control.Button
        Image11                 matlab.ui.control.Image
        Image10                 matlab.ui.control.Image
        EditField_3             matlab.ui.control.EditField
        Image                   matlab.ui.control.Image
    end

  
    properties (Access = public)
        Property2 % Description
        xScale = 1; % X 轴初始比例
        yScale = 1; % Y 轴初始比例
        image6 = '';
        gauss_mean = 0;
        gauss_v = 0.01;
        grayImg = '';
    end

    
    methods (Access = private)
        
        function grayImg = myRgb2Gray(~, img)
            % 手动实现RGB转灰度的公式
            R = img(:,:,1);
            G = img(:,:,2);
            B = img(:,:,3);
            grayImg = 0.2989 * R + 0.5870 * G + 0.1140 * B; % 使用加权平均法
        end
        
        function myHist(~,img)
            % 计算直方图并显示
            [counts, grayLevels] = histcounts(img, 0:255); % 计算灰度级别的分布
            
            % 绘制直方图
            figure;
            bar(grayLevels(1:end-1), counts, 'histc');
            xlabel('灰度级');
            ylabel('像素数量');
            title('灰度直方图');
        end

        function matchedImg = myHistMatch(~,inputImg, refImg)
            % 转换为灰度图
            inputGray = rgb2gray(inputImg);
            refGray = rgb2gray(refImg);
        
            % 计算输入图像和参考图像的累积分布函数（CDF）
            [inputCounts, inputLevels] = histcounts(inputGray, 0:255);
            inputCDF = cumsum(inputCounts) / numel(inputGray);
        
            [refCounts, refLevels] = histcounts(refGray, 0:255);
            refCDF = cumsum(refCounts) / numel(refGray);
        
            % 为每个输入图像的灰度值查找匹配的参考灰度值
            matchedImg = inputGray;
            for i = 1:length(inputLevels)-1
                % 寻找最接近的参考CDF值
                [~, idx] = min(abs(inputCDF(i) - refCDF));  % 计算输入图像与参考图像的匹配
                matchedImg(inputGray == inputLevels(i)) = refLevels(idx);
            end
        end

        function equalizedImg = myHistogramEqualization(~,img)
            % 将图像像素值归一化到 [0, 1] 区间
            img = double(img) / 255;
            
            % 计算图像的直方图
            [counts, grayLevels] = imhist(uint8(img * 255));
            
            % 归一化直方图
            counts = counts / sum(counts);
            
            % 计算累积分布函数（CDF）
            cdf = cumsum(counts);
            
            % 将CDF映射到新的灰度级
            newGrayLevels = round(cdf * 255);
            
            % 使用新的灰度级来进行映射，生成均衡化后的图像
            equalizedImg = uint8(newGrayLevels(uint8(img * 255) + 1));
            
            % 将图像像素值转换回 [0, 255] 区间
            equalizedImg = uint8(equalizedImg);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            % 打开本地文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                                      '选择一张图片');
            % 检查用户是否选择了文件
            if isequal(file, 0)
                return; % 如果没有选择文件，则退出回调函数
            end
    
            % 构建完整文件路径
            filePath = fullfile(path, file);
    
            % 在 Image 控件中显示选择的图片
            app.Image.ImageSource = filePath;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % 检查当前按钮并执行相应操作
            if selectedButton == app.Button_2
                % 灰度化
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
        
                % 读取图片并转换为灰度图
                img = imread(app.Image.ImageSource);
                mygray= myRgb2Gray(app,img);
                gray = rgb2gray(img);
                % 保存并显示结果
                outputFile = ['mygray_result_', num2str(now, '%.10f'), '.png'];
                imwrite(mygray, outputFile);
                output = ['gray_result_', num2str(now, '%.10f'), '.png'];
                imwrite(gray, output);
                app.Image2.ImageSource = outputFile;
                app.Image3.ImageSource = output;
                app.grayImg = outputFile;
            
            elseif selectedButton == app.Button_3
                % 直方图显示
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
                if isempty(app.grayImg)
                    uialert(app.UIFigure, '请先进行灰度化！', '错误');
                    return;
                end
        
                % 读取图片并绘制直方图
                img = imread(app.grayImg);
                myHist(app,img); % 使用自定义直方图显示函数
                histFile = ['histogram_result_', num2str(now, '%.10f'), '.png'];
                saveas(gcf, histFile); % 保存为图片
                app.Image3.ImageSource = histFile;
        
            elseif selectedButton == app.Button_4
                % 直方图均衡化
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
                if isempty(app.grayImg)
                    uialert(app.UIFigure, '请先进行灰度化！', '错误');
                    return;
                end
                % 读取图片并均衡化
                img = imread(app.grayImg);
                equalizedImg = myHistogramEqualization(app,img); % 使用自定义均衡化函数
        
                % 保存均衡化图像
                equalizedFile = 'equalized_result.png';
                imwrite(equalizedImg, equalizedFile);
%                 app.Image4.ImageSource = equalizedFile;
        
                % 保存均衡化后的直方图
                histEqFile = ['equalized_histogram_', num2str(now, '%.10f'), '.png'];
                figure; imhist(equalizedImg); % 绘制直方图
                saveas(gcf, histEqFile); % 保存图片
                app.Image4.ImageSource = histEqFile;
        
            elseif selectedButton == app.Button_5
                % 直方图匹配（规定化）
                if isempty(app.Image.ImageSource)
                    uialert(app.UIFigure, '请先加载图片！', '错误');
                    return;
                end
        
                gray = imread(app.grayImg);
               % 生成随机参考灰度图像
                referenceGray = uint8(255 * rand(size(gray))); % 大小与输入图像相同
                
                % 匹配直方图
                matchedImg = imhistmatch(gray, referenceGray);
        
                % 保存匹配后图像
                matchedFile = 'matched_result.png';
                imwrite(matchedImg, matchedFile);
                %app.Image5.ImageSource = matchedFile;
        
                % 保存匹配后的直方图
                matchedHistFile = ['matched_histogram_', num2str(now, '%.10f'), '.png'];
                figure; imhist(matchedImg); % 绘制直方图
                saveas(gcf, matchedHistFile); % 保存图片
                app.Image5.ImageSource = matchedHistFile;
        
            else
                % 未知操作
                uialert(app.UIFigure, '未知操作', '错误');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            
            % 读取原始图片和尺寸
            img = imread(app.Image.ImageSource);
            [origHeight, origWidth, numChannels] = size(img);
            scaleValue = value / 100; % 全局缩放比例
            
            % 缩放图片
            scaledImg = imresize(img, scaleValue);
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            
            % 创建黑色背景
            if numChannels == 3
                background = zeros(origHeight, origWidth, 3, 'uint8'); % RGB 黑色背景
            else
                background = zeros(origHeight, origWidth, 'uint8'); % 灰度图黑色背景
            end
            
            % 计算嵌入位置并填充缩放后的图像
            top = floor((origHeight - scaledHeight) / 2) + 1;
            left = floor((origWidth - scaledWidth) / 2) + 1;
            background(top:top + scaledHeight - 1, left:left + scaledWidth - 1, :) = scaledImg;
            
            % 保存并更新 Image6 显示
            scaledFile = ['scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
            app.image6 = scaledFile;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = -value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            brightnessValue = app.Slider_2.Value; % 获取亮度值
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
            % 应用亮度调整
            imgLinear = img + brightnessValue / 100;
            imgLinear = imgLinear * (1 + app.Slider_3.Value / 100); % 当前对比度值
            imgLinear = min(max(imgLinear, 0), 1); % 限制在 [0,1] 范围
            % 更新线性变换结果显示
            linearFile = ['linear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgLinear, linearFile);
            app.Image6_2.ImageSource = linearFile;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            contrastValue = app.Slider_3.Value; % 获取对比度值
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
            % 应用对比度调整
            imgLinear = img + app.Slider_2.Value / 100; % 当前亮度值
            imgLinear = imgLinear * (1 + contrastValue / 100);
            imgLinear = min(max(imgLinear, 0), 1); % 限制在 [0,1] 范围
            % 更新线性变换结果显示
            linearFile = ['linear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgLinear, linearFile);
            app.Image6_2.ImageSource = linearFile;
        end

        % Callback function
        function EditField_2ValueChanged(app, event)

            % 获取输入值，用于控制噪声类型
            noiseType = app.EditField_2.Value;
        
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            % 加载原始图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转换为 double 类型方便处理
        
            % 加噪处理
            if noiseType == 1
                % 高斯噪声
                noisyImg = imnoise(img, 'gaussian', 0, 0.01);
            elseif noiseType == 2
                % 盐椒噪声
                noisyImg = imnoise(img, 'salt & pepper', 0.02);
            elseif noiseType == 3
                % 均匀噪声
                noisyImg = img + 0.1 * (rand(size(img)) - 0.5);
                noisyImg = min(max(noisyImg, 0), 1); % 限制范围到 [0,1]
            else
                uialert(app.UIFigure, '请输入有效的噪声类型值（1: 高斯, 2: 盐椒, 3: 均匀）！', '错误');
                return;
            end
        
            % 更新加噪图像显示
            noisyFile = ['noisy_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_3.ImageSource = noisyFile;
        
            % 空域滤波（中值滤波）
            filteredImgSpatial = medfilt2(rgb2gray(noisyImg), [3 3]); % 灰度处理后中值滤波
            spatialFile = ['spatial_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImgSpatial, spatialFile);
            app.Image8.ImageSource = spatialFile;
        
            % 频域滤波（理想低通滤波）
            [rows, cols] = size(rgb2gray(noisyImg)); % 获取图像大小
            D0 = 30; % 截止频率
            [U, V] = meshgrid(1:cols, 1:rows);
            D = sqrt((U - cols / 2).^2 + (V - rows / 2).^2);
            H = double(D <= D0); % 低通滤波器
            
            % 频域处理
            fftImg = fftshift(fft2(rgb2gray(noisyImg)));
            filteredImgFreq = real(ifft2(ifftshift(fftImg .* H)));
            filteredImgFreq = mat2gray(filteredImgFreq); % 归一化
            
            freqFile = ['frequency_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImgFreq, freqFile);
            app.Image9.ImageSource = freqFile;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
    
            % 读取原图像
            img = imread(app.Image.ImageSource); % 从app.Image.ImageSource获取原图像路径
            img_gray = rgb2gray(img); % 将图像转为灰度图像
        
            % 根据选择的算子进行边缘检测
            switch value
                case '罗伯特算子'
                    % 使用Robert算子进行边缘检测
                    edge_img = edge(img_gray, 'Roberts');
                case 'Prewitt算子'
                    % 使用Prewitt算子进行边缘检测
                    edge_img = edge(img_gray, 'Prewitt');
                case 'Sobel算子'
                    % 使用Sobel算子进行边缘检测
                    edge_img = edge(img_gray, 'Sobel');
                case '拉普拉斯算子'
                    % 使用拉普拉斯算子进行边缘检测
                    edge_img = edge(img_gray, 'log'); % 'log'表示拉普拉斯算子
                otherwise
                    % 默认行为
                    edge_img = img_gray;
            end
            
            % 保存边缘检测图像并更新Image10
            edgeFile = ['edge_image_', num2str(now, '%.10f'), '.png'];
            imwrite(edge_img, edgeFile); % 保存边缘检测图像
            app.Image10.ImageSource = edgeFile; % 更新Image10控件的ImageSource为边缘图像
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 读取原图像
            img = imread(app.Image.ImageSource);  % 获取原图像
            
            % 转为灰度图像
            img_gray = rgb2gray(img);
            
            % 目标提取（基于阈值的简单分割示例，您可以根据需要替换为更复杂的算法）
            threshold = 0.5;  % 设置阈值
            binary_img = imbinarize(img_gray, threshold);  % 二值化图像进行目标提取
            
            % 进行形态学操作去除噪声（可选）
            se = strel('disk', 5);  % 结构元素
            binary_img = imopen(binary_img, se);  % 开运算去噪
            
            % 保存目标提取图像并更新Image11
            targetFile = ['target_image_', num2str(now, '%.10f'), '.png'];
            imwrite(binary_img, targetFile);  % 保存目标提取图像
            app.Image11.ImageSource = targetFile;  % 更新Image11控件的ImageSource为目标提取结果
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
           value = app.DropDown_4.Value;  % 获取下拉框选项

            % 读取原图像和目标提取图像
            img = imread(app.Image.ImageSource);  % 获取原图像
            img_gray = rgb2gray(img);  % 将图像转为灰度图像
            target_img = imread(app.Image11.ImageSource);  % 获取目标提取后的图像
            
            % 特征提取结果初始化
            originalFeatures = [];
            targetFeatures = [];
            
            % 根据选择的特征提取方法进行特征提取
            switch value
                case 'LBP'
                    % LBP特征提取
                    originalFeatures = extractLBPFeatures(img_gray);
                    targetFeatures = extractLBPFeatures(target_img);
                case 'HOG'
                    % HOG特征提取
                    [originalFeatures, ~] = extractHOGFeatures(img_gray);
                    [targetFeatures, ~] = extractHOGFeatures(target_img);
            end
        
            % 更新TextArea显示特征提取结果
            app.TextArea.Value = sprintf("Original Features: %s\nTarget Features: %s", ...
                mat2str(originalFeatures, 5), mat2str(targetFeatures, 5));
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            disp(['Text Area updated: ', value]);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end

        % Value changed function: cSpinner_2
        function cSpinner_2ValueChanged(app, event)
            value = app.cSpinner_2.Value;
            
            % 加载彩色图像
            img = imread(app.Image.ImageSource); 
            img = im2double(img); % 转为 double 类型方便计算
        
            % 获取图像的三个颜色通道：R, G, B
            R = img(:,:,1); % 红色通道
            G = img(:,:,2); % 绿色通道
            B = img(:,:,3); % 蓝色通道
        
            % 定义对数变换公式，分别对每个通道进行变换
            c = value; % 使用编辑框中的常数作为 c
            R = c * log(1 + R); % 对红色通道应用对数变换
            G = c * log(1 + G); % 对绿色通道应用对数变换
            B = c * log(1 + B); % 对蓝色通道应用对数变换
        
            % 限制输出像素值在 [0, 1] 范围内
            R = mat2gray(R); 
            G = mat2gray(G); 
            B = mat2gray(B); 
        
            % 合并变换后的颜色通道
            imgNonlinear = cat(3, R, G, B); 
        
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear0_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile); % 保存变换后的图像
        
            % 更新界面上的显示图像
            app.Image7_2.ImageSource = nonlinearFile;
        end

        % Value changed function: cSpinner
        function cSpinnerValueChanged(app, event)
            value = app.cSpinner.Value;
            % 加载彩色图像
            img = imread(app.Image.ImageSource); 
            img = im2double(img); % 转为 double 类型方便计算
        
            % 获取图像的三个颜色通道：R, G, B
            R = img(:,:,1); % 红色通道
            G = img(:,:,2); % 绿色通道
            B = img(:,:,3); % 蓝色通道
        
            % 定义指数变换公式，分别对每个通道进行变换
            c = value; % 使用编辑框中的常数作为 c
            R = c * (exp(R) - 1); % 对红色通道应用指数变换
            G = c * (exp(G) - 1); % 对绿色通道应用指数变换
            B = c * (exp(B) - 1); % 对蓝色通道应用指数变换
        
            % 合并变换后的颜色通道
            imgNonlinear = cat(3, R, G, B); 
        
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear1_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile); % 保存变换后的图像
        
            % 更新界面上的显示图像
            app.Image7_2.ImageSource = nonlinearFile;
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            xScaleValue = app.xSlider.Value / 100; % X 轴缩放比例
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            if isempty(app.image6)
                img = imread(app.Image.ImageSource);
            else
                img = imread(app.image6);
            end
            
            % 读取原始图片尺寸
            originalImg = imread(app.Image.ImageSource);
            [origHeight, origWidth, numChannels] = size(originalImg);
            
            % 缩放图片
            [~, origWidth, numChannels] = size(originalImg);
            newWidth = round(origWidth * xScaleValue);
            scaledImg = imresize(img, [origHeight, newWidth]); % 按宽度缩放，保持高度不变
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            
            % 创建黑色背景
            if numChannels == 3
                background = zeros(origHeight, origWidth, 3, 'uint8'); % RGB 黑色背景
            else
                background = zeros(origHeight, origWidth, 'uint8'); % 灰度图黑色背景
            end
            
            % 嵌入缩放后的图像
            top = floor((origHeight - scaledHeight) / 2) + 1;
            left = floor((origWidth - scaledWidth) / 2) + 1;
            background(top:top + scaledHeight - 1, left:left + scaledWidth - 1, :) = scaledImg;
            
            % 保存并更新显示
            scaledFile = ['x_scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            yScaleValue = app.ySlider.Value / 100; % Y 轴缩放比例
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            if isempty(app.image6)
                img = imread(app.Image.ImageSource);
            else
                img = imread(app.image6);
            end
            
            % 读取原始图片尺寸
            originalImg = imread(app.Image.ImageSource);
            [origHeight, origWidth, numChannels] = size(originalImg);
            
            % 缩放图片
            newHeight = round(origHeight * yScaleValue);
            scaledImg = imresize(img, [newHeight, origWidth]); % 按高度缩放，保持宽度不变
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            
            % 创建黑色背景
            if numChannels == 3
                background = zeros(origHeight, origWidth, 3, 'uint8'); % RGB 黑色背景
            else
                background = zeros(origHeight, origWidth, 'uint8'); % 灰度图黑色背景
            end
            
            % 嵌入缩放后的图像
            top = floor((origHeight - scaledHeight) / 2) + 1;
            left = floor((origWidth - scaledWidth) / 2) + 1;
            background(top:top + scaledHeight - 1, left:left + scaledWidth - 1, :) = scaledImg;
            
            % 保存并更新显示
            scaledFile = ['y_scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Value changed function: meanEditField
        function meanEditFieldValueChanged(app, event)
            value = app.meanEditField.Value;
            app.gauss_mean = value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = imnoise(img, 'gaussian', app.gauss_mean , app.gauss_v);
            % 更新加噪图像显示
            noisyFile = ['noisy_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_3.ImageSource = noisyFile;
        end

        % Value changed function: varianceEditField
        function varianceEditFieldValueChanged(app, event)
            value = app.varianceEditField.Value;
            app.gauss_v = value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = imnoise(img, 'gaussian', app.gauss_mean , app.gauss_v);
            % 更新加噪图像显示
            noisyFile = ['noisy_gauss_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_3.ImageSource = noisyFile;
        end

        % Value changed function: densitySpinner
        function densitySpinnerValueChanged(app, event)
            value = app.densitySpinner.Value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = imnoise(img, 'salt & pepper', value);
            % 更新加噪图像显示
            noisyFile = ['noisy_density_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image12_3.ImageSource = noisyFile;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            img = imread(app.Image.ImageSource);
            img = im2double(img);
            noisyImg = img + value * (rand(size(img)) - 0.5);
            noisyImg = min(max(noisyImg, 0), 1); % 限制范围到 [0,1]
            % 更新加噪图像显示
            noisyFile = ['noisy_spinner_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image12_4.ImageSource = noisyFile;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % 读取图片
    img = imread(app.Image.ImageSource);

    % 预处理图片
    img = imresize(img, [448, 448]);           % 调整为模型需要的输入尺寸
    img = double(img) / 255;                  % 归一化到 [0, 1]
    img = reshape(img, [448, 448, 3]);        % 确保为 HWC 格式（高度、宽度、通道）

    % 加载 ONNX 模型
    onnxModel = importONNXNetwork('model.onnx', 'OutputLayerType', 'classification');

    % 使用 ONNX 模型进行预测
    output = predict(onnxModel, img);          % 输入为 HWC 格式
    [~, predictedClass] = max(output, [], 2); % 获取预测结果

    % 显示预测结果
    app.EditField_3.Value = ['预测标签：', num2str(predictedClass)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像预处理';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '灰度化';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '直方图显示';
            app.Button_3.Value = true;
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '直方图均衡化';
            app.Button_5 = uitogglebutton(app.ButtonGroup);
            app.Button_5.Text = '直方图匹配';
            app.Image2 = uiimage(app.Tab);
            app.Image3 = uiimage(app.Tab);
            app.Image4 = uiimage(app.Tab);
            app.Image5 = uiimage(app.Tab);
            app.Label = uilabel(app.Tab);
            app.Label.Text = '灰度图像';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '直方图';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '直方图均衡化';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '直方图匹配';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '对比度增强';
            app.Image6_2 = uiimage(app.Tab_3);
            app.Label_11 = uilabel(app.Tab_3);
            app.Label_11.Text = '线性变换';
            app.Label_13 = uilabel(app.Tab_3);
            app.Label_13.Text = '亮度';
            app.Slider_2 = uislider(app.Tab_3);
            app.Slider_2.Limits = [-100 100];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_14 = uilabel(app.Tab_3);
            app.Label_14.Text = '对比度';
            app.Slider_3 = uislider(app.Tab_3);
            app.Slider_3.Limits = [-100 100];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.TabGroup2 = uitabgroup(app.Tab_3);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '对数变换';
            app.gxyclog1fxyLabel = uilabel(app.Tab_4);
            app.gxyclog1fxyLabel.Text = 'g(x,y)=c⋅log(1+f(x,y))';
            app.cSpinner_2Label = uilabel(app.Tab_4);
            app.cSpinner_2Label.Text = 'c';
            app.cSpinner_2 = uispinner(app.Tab_4);
            app.cSpinner_2.Step = 0.5;
            app.cSpinner_2.Limits = [0 15];
            app.cSpinner_2.ValueChangedFcn = createCallbackFcn(app, @cSpinner_2ValueChanged, true);
            app.cSpinner_2.Value = 1;
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '指数变换';
            app.gxycefxy1Label = uilabel(app.Tab_5);
            app.gxycefxy1Label.Text = 'g(x,y)= c⋅(e^f(x,y) -1)';
            app.cSpinnerLabel = uilabel(app.Tab_5);
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.Tab_5);
            app.cSpinner.Step = 0.5;
            app.cSpinner.Limits = [0 15];
            app.cSpinner.ValueChangedFcn = createCallbackFcn(app, @cSpinnerValueChanged, true);
            app.cSpinner.Value = 1;
            app.Image7_2 = uiimage(app.Tab_3);
            app.Label_28 = uilabel(app.Tab_3);
            app.Label_28.Text = '非线性变换';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '图像变换';
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '按比例缩放(%)';
            app.Slider = uislider(app.Tab_2);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.Image6 = uiimage(app.Tab_2);
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '缩放变换';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '旋转变换';
            app.Image7 = uiimage(app.Tab_2);
            app.Label_27 = uilabel(app.Tab_2);
            app.Label_27.Text = '旋转角度';
            app.Knob = uiknob(app.Tab_2, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.xSliderLabel = uilabel(app.Tab_2);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.Tab_2);
            app.xSlider.Limits = [1 100];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Value = 1;
            app.ySliderLabel = uilabel(app.Tab_2);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.Tab_2);
            app.ySlider.Limits = [1 100];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.Value = 1;
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '图像加噪';
            app.TabGroup3 = uitabgroup(app.Tab_6);
            app.Tab_8 = uitab(app.TabGroup3);
            app.Tab_8.Title = '高斯噪声';
            app.Image6_3 = uiimage(app.Tab_8);
            app.meanEditFieldLabel = uilabel(app.Tab_8);
            app.meanEditFieldLabel.Text = '均值 (mean)';
            app.meanEditField = uieditfield(app.Tab_8, 'numeric');
            app.meanEditField.ValueChangedFcn = createCallbackFcn(app, @meanEditFieldValueChanged, true);
            app.varianceEditFieldLabel = uilabel(app.Tab_8);
            app.varianceEditFieldLabel.Text = '方差 (variance)';
            app.varianceEditField = uieditfield(app.Tab_8, 'numeric');
            app.varianceEditField.ValueChangedFcn = createCallbackFcn(app, @varianceEditFieldValueChanged, true);
            app.Button_7 = uibutton(app.Tab_8, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '空域滤波';
            app.Button_8 = uibutton(app.Tab_8, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '频域滤波';
            app.Image12 = uiimage(app.Tab_8);
            app.Image12_5 = uiimage(app.Tab_8);
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '盐椒噪声';
            app.Image12_3 = uiimage(app.Tab_9);
            app.densitySpinnerLabel = uilabel(app.Tab_9);
            app.densitySpinnerLabel.Text = '噪声密度 (density)';
            app.densitySpinner = uispinner(app.Tab_9);
            app.densitySpinner.Step = 0.01;
            app.densitySpinner.Limits = [0 1];
            app.densitySpinner.ValueChangedFcn = createCallbackFcn(app, @densitySpinnerValueChanged, true);
            app.Button_9 = uibutton(app.Tab_9, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '空域滤波';
            app.Button_10 = uibutton(app.Tab_9, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '频域滤波';
            app.Image12_6 = uiimage(app.Tab_9);
            app.Image12_7 = uiimage(app.Tab_9);
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '均匀噪声';
            app.Image12_4 = uiimage(app.Tab_10);
            app.SpinnerLabel = uilabel(app.Tab_10);
            app.SpinnerLabel.Text = '噪声强度（系数）';
            app.Spinner = uispinner(app.Tab_10);
            app.Spinner.Step = 0.1;
            app.Spinner.Limits = [-0.5 0.5];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Button_11 = uibutton(app.Tab_10, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '空域滤波';
            app.Button_12 = uibutton(app.Tab_10, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '频域滤波';
            app.Image12_8 = uiimage(app.Tab_10);
            app.Image12_9 = uiimage(app.Tab_10);
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '边缘检测';
            app.Image10 = uiimage(app.Tab_7);
            app.Image11 = uiimage(app.Tab_7);
            app.Button_6 = uibutton(app.Tab_7, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '目标提取';
            app.TextArea = uitextarea(app.Tab_7);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Label_21 = uilabel(app.Tab_7);
            app.Label_21.Text = '边缘检测';
            app.DropDown = uidropdown(app.Tab_7);
            app.DropDown.Items = {'罗伯特算子', 'Prewitt算子', 'Sobel算子', '拉普拉斯算子'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '罗伯特算子';
            app.Label_26 = uilabel(app.Tab_7);
            app.Label_26.Text = '特征提取';
            app.DropDown_4 = uidropdown(app.Tab_7);
            app.DropDown_4.Items = {'LBP', 'HOG'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'LBP';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '图像分离';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end